classdef ProjectUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        LineTab                        matlab.ui.container.Tab
        ErrorMessageLabel              matlab.ui.control.Label
        GraphDataButton                matlab.ui.control.Button
        SelectComparisonDropDown       matlab.ui.control.DropDown
        SelectComparisonDropDownLabel  matlab.ui.control.Label
        CompareCountriesandRegionsovertimeandthroughcrisesLabel  matlab.ui.control.Label
        CountryRegion2EditField        matlab.ui.control.EditField
        CountryRegion2EditFieldLabel   matlab.ui.control.Label
        CountryRegion1EditField        matlab.ui.control.EditField
        CountryRegion1EditFieldLabel   matlab.ui.control.Label
        MajorEventDropDown             matlab.ui.control.DropDown
        MajorEventDropDownLabel        matlab.ui.control.Label
        Slider                         matlab.ui.control.Slider
        SliderLabel                    matlab.ui.control.Label
        UIAxes_2A                      matlab.ui.control.UIAxes
        UIAxesA                        matlab.ui.control.UIAxes
        CountriesTab                   matlab.ui.container.Tab
        Label_6                        matlab.ui.control.Label
        Label_5                        matlab.ui.control.Label
        Label_4                        matlab.ui.control.Label
        Label_3                        matlab.ui.control.Label
        Label_2                        matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        AcceptableCountryRegionInputsLabel  matlab.ui.control.Label
        ExplanationTab                 matlab.ui.container.Tab
        MeanYearsofSchoolingLabel      matlab.ui.control.Label
        LifeExpectancyLabel            matlab.ui.control.Label
        GNILabel                       matlab.ui.control.Label
        Label_9                        matlab.ui.control.Label
        Label_8                        matlab.ui.control.Label
        Label_7                        matlab.ui.control.Label
    end


    properties (Access = public)
        s = [] % data structure with all country arrays
        country1 = []; % array of country 1
        country2 = []; % array of country 2
        years = []; % years vector
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.ErrorMessageLabel.Visible = 'off';
        end

        % Value changed function: SelectComparisonDropDown
        function SelectComparisonDropDownValueChanged(app, event)
            value = app.SelectComparisonDropDown.Value;
            Project(app);
            % Plot(app);
        end

        % Value changed function: CountryRegion1EditField
        function CountryRegion1EditFieldValueChanged(app, event)
            value = app.CountryRegion1EditField.Value;

        end

        % Value changed function: CountryRegion2EditField
        function CountryRegion2EditFieldValueChanged(app, event)
            value = app.CountryRegion2EditField.Value;

        end

        % Button pushed function: GraphDataButton
        function GraphDataButtonPushed(app, event)
            Project(app);
            Plot(app);
        end

        % Value changed function: MajorEventDropDown
        function MajorEventDropDownValueChanged(app, event)
            value = app.MajorEventDropDown.Value;

        end

        % Button down function: UIAxesA
        function UIAxesAButtonDown(app, event)

        end

        % Button down function: UIAxes_2A
        function UIAxes_2AButtonDown(app, event)

        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            value = app.Slider.Value;

            x_target = app.Slider.Value;

            if ~isempty(app.CountryRegion1EditField.Value) || ~isempty(app.CountryRegion2EditField.Value)
                %% New function interp1 for linear interpolation
                % Interpolate for Country 1
                y1_target = interp1(app.years, app.country1, x_target, 'linear');

                % Interpolate for Country 2
                y2_target = interp1(app.years, app.country2, x_target, 'linear');

                %% New function findall for linear interpolation
                % Delete existing slider dots (optional: tag them and delete by tag)
                delete(findall(app.UIAxesA, 'Tag', 'SliderDot'));
                delete(findall(app.UIAxes_2A, 'Tag', 'SliderDot'));

                % Plot new interpolated point (slider dot)
                %% New function isnan for linear interpolation
                % Makes sure the target values a number and plots
                if ~isnan(y1_target)
                    plot(app.UIAxesA, x_target, y1_target, 'kx', ...
                        'MarkerSize', 10, 'LineWidth', 2, 'Tag', 'SliderDot');
                end
                % Makes sure the target values a number and plots
                if ~isnan(y2_target)
                    plot(app.UIAxes_2A, x_target, y2_target, 'kx', ...
                        'MarkerSize', 10, 'LineWidth', 2, 'Tag', 'SliderDot');
                end

            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.LineTab = uitab(app.TabGroup);
            app.LineTab.Title = 'Line';
            app.UIAxesA = uiaxes(app.LineTab);
            title(app.UIAxesA, 'Country/Region 1')
            xlabel(app.UIAxesA, 'X')
            ylabel(app.UIAxesA, 'Y')
            zlabel(app.UIAxesA, 'Z')
            app.UIAxesA.ButtonDownFcn = createCallbackFcn(app, @UIAxesAButtonDown, true);
            app.UIAxes_2A = uiaxes(app.LineTab);
            title(app.UIAxes_2A, 'Country/Region 2')
            xlabel(app.UIAxes_2A, 'X')
            ylabel(app.UIAxes_2A, 'Y')
            zlabel(app.UIAxes_2A, 'Z')
            app.UIAxes_2A.ButtonDownFcn = createCallbackFcn(app, @UIAxes_2AButtonDown, true);
            app.SliderLabel = uilabel(app.LineTab);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.LineTab);
            app.Slider.Limits = [2000 2022];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.Value = 2000;
            app.MajorEventDropDownLabel = uilabel(app.LineTab);
            app.MajorEventDropDownLabel.Text = 'Major Event';
            app.MajorEventDropDown = uidropdown(app.LineTab);
            app.MajorEventDropDown.Items = {'2002 Financial Crisis', '2008 Recession', '2020 Covid-19 Pandemic'};
            app.MajorEventDropDown.ValueChangedFcn = createCallbackFcn(app, @MajorEventDropDownValueChanged, true);
            app.MajorEventDropDown.Value = '2002 Financial Crisis';
            app.CountryRegion1EditFieldLabel = uilabel(app.LineTab);
            app.CountryRegion1EditFieldLabel.Text = 'Country/Region 1';
            app.CountryRegion1EditField = uieditfield(app.LineTab, 'text');
            app.CountryRegion1EditField.ValueChangedFcn = createCallbackFcn(app, @CountryRegion1EditFieldValueChanged, true);
            app.CountryRegion2EditFieldLabel = uilabel(app.LineTab);
            app.CountryRegion2EditFieldLabel.Text = 'Country/Region 2';
            app.CountryRegion2EditField = uieditfield(app.LineTab, 'text');
            app.CountryRegion2EditField.ValueChangedFcn = createCallbackFcn(app, @CountryRegion2EditFieldValueChanged, true);
            app.CompareCountriesandRegionsovertimeandthroughcrisesLabel = uilabel(app.LineTab);
            app.CompareCountriesandRegionsovertimeandthroughcrisesLabel.Text = 'Compare Countries and Regions over time and through crises';
            app.SelectComparisonDropDownLabel = uilabel(app.LineTab);
            app.SelectComparisonDropDownLabel.Text = 'Select Comparison';
            app.SelectComparisonDropDown = uidropdown(app.LineTab);
            app.SelectComparisonDropDown.Items = {'GNI', 'Life Expectancy', 'Mean Years of Schooling'};
            app.SelectComparisonDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectComparisonDropDownValueChanged, true);
            app.SelectComparisonDropDown.Value = 'GNI';
            app.GraphDataButton = uibutton(app.LineTab, 'push');
            app.GraphDataButton.ButtonPushedFcn = createCallbackFcn(app, @GraphDataButtonPushed, true);
            app.GraphDataButton.Text = 'Graph Data';
            app.ErrorMessageLabel = uilabel(app.LineTab);
            app.ErrorMessageLabel.Text = {'Error, one of your country/region names is incorrect.'; 'Please consult the "Countries" tab for acceptable inputs.'};
            app.CountriesTab = uitab(app.TabGroup);
            app.CountriesTab.Title = 'Countries';
            app.AcceptableCountryRegionInputsLabel = uilabel(app.CountriesTab);
            app.AcceptableCountryRegionInputsLabel.Text = 'Acceptable Country/Region Inputs';
            app.Label = uilabel(app.CountriesTab);
            app.Label.Text = {'Afghanistan  '; 'Albania  '; 'Algeria  '; 'Andorra  '; 'Angola  '; 'Antigua and Barbuda  '; 'Argentina  '; 'Armenia  '; 'Australia  '; 'Austria  '; 'Azerbaijan  '; 'Bahamas  '; 'Bahrain  '; 'Bangladesh  '; 'Barbados  '; 'Belarus  '; 'Belgium  '; 'Belize  '; 'Benin  '; 'Bhutan  '; 'Bolivia  '; 'Bosnia and Herzegovina  '; 'Botswana  '; 'Brazil  '; 'Brunei  '; 'Bulgaria  '; 'Burkina Faso '; 'Burundi  '; 'Cabo Verde  '; 'Cambodia  '; 'Cameroon '; 'Canada  '; 'Central African Republic  '; 'Chad  '; 'Chile  '; 'China  '; 'Colombia'; ''};
            app.Label_2 = uilabel(app.CountriesTab);
            app.Label_2.Text = {'Comoros  '; 'Congo  '; 'Costa Rica   '; 'Croatia  '; 'Cuba  '; 'Cyprus  '; 'Czechia  '; 'Denmark  '; 'Djibouti  '; 'Dominica  '; 'Dominican Republic  '; 'Ecuador  '; 'Egypt  '; 'El Salvador  '; 'Equatorial Guinea  '; 'Eritrea  '; 'Estonia  '; 'Eswatini  '; 'Ethiopia  '; 'Fiji  '; 'Finland  '; 'France  '; 'Gabon  '; 'Gambia  '; 'Georgia  '; 'Germany  '; 'Ghana  '; 'Greece  '; 'Grenada  '; 'Guatemala  '; 'Guinea  '; 'Guyana  '; 'Haiti  '; 'Honduras  '; 'Hungary  '; ''};
            app.Label_3 = uilabel(app.CountriesTab);
            app.Label_3.Text = {'Iceland  '; 'India  '; 'Indonesia  '; 'Iran  '; 'Iraq  '; 'Ireland  '; 'Israel '; 'Italy  '; 'Jamaica  '; 'Japan  '; 'Jordan  '; 'Kazakhstan  '; 'Kenya  '; 'Kiribati  '; 'Korea  '; 'Kuwait  '; 'Kyrgyzstan  '; 'Latvia  '; 'Lebanon  '; 'Lesotho  '; 'Liberia  '; 'Libya  '; 'Liechtenstein  '; 'Lithuania  '; 'Luxembourg  '; 'Madagascar  '; 'Malawi  '; 'Malaysia  '; 'Maldives  '; 'Mali  '; 'Malta  '; 'Marshall Islands '; 'Mauritania  '; 'Mauritius  '; 'Mexico  '};
            app.Label_4 = uilabel(app.CountriesTab);
            app.Label_4.Text = {'Micronesia  '; 'Moldova  '; 'Monaco  '; 'Mongolia  '; 'Montenegro  '; 'Morocco  '; 'Mozambique  '; 'Myanmar  '; 'Namibia  '; 'Nauru  '; 'Nepal  '; 'Netherlands  '; 'New Zealand  '; 'Nicaragua  '; 'Niger  '; 'Nigeria  '; 'North Macedonia  '; 'Norway  '; 'Oman  '; 'Pakistan  '; 'Palau  '; 'Panama  '; 'Papua New Guinea  '; 'Paraguay  '; 'Peru  '; 'Philippines  '; 'Poland  '; 'Portugal  '; 'Qatar  '; 'Romania  '; 'Russia  '; 'Rwanda  '; 'Saint Kitts and Nevis  '; 'Saint Lucia  '; 'Saint Vincent and the Grenadines  '; ''; ''};
            app.Label_5 = uilabel(app.CountriesTab);
            app.Label_5.Text = {'Samoa  '; 'San Marino  '; 'Sao Tome and Principe  '; 'Saudi Arabia  '; 'Senegal  '; 'Serbia  '; 'Seychelles  '; 'Sierra Leone  '; 'Singapore  '; 'Slovakia  '; 'Slovenia  '; 'Solomon Islands  '; 'Somalia  '; 'South Africa  '; 'South Sudan  '; 'Spain  '; 'Sri Lanka  '; 'Sudan  '; 'Suriname  '; 'Sweden  '; 'Switzerland  '; 'Syria  '; 'Tajikistan  '; 'Tanzania  '; 'Thailand  '; 'Togo  '; 'Tonga  '; 'Trinidad and Tobago  '; 'Tunisia  '; 'Turkey  '; 'Turkmenistan  '; 'Tuvalu  '; 'Uganda  '; ''; ''};
            app.Label_6 = uilabel(app.CountriesTab);
            app.Label_6.Text = {'Ukraine  '; 'United Arab Emirates  '; 'United Kingdom  '; 'United States  '; 'Uruguay  '; 'Uzbekistan  '; 'Vanuatu  '; 'Venezuela  '; 'Yemen  '; 'Zambia  '; 'Zimbabwe  '; 'Ivory Coast  '; 'Laos  '; 'Guinea Bissau  '; 'Hong Kong  '; 'Palestine  '; 'Timor Leste  '; 'Vietnam  '; 'North America'; 'South America'; 'Europe'; 'Asia'; 'Africa'; 'Oceania'; 'World'};
            app.ExplanationTab = uitab(app.TabGroup);
            app.ExplanationTab.Title = 'Explanation';
            app.Label_7 = uilabel(app.ExplanationTab);
            app.Label_7.Text = {'GNI (Gross National Income) is the total money earned by the people and businesses of a country in one year.'; ''; 'It includes:'; ''; 'Money made inside the country (like in GDP)'; ''; 'Plus money earned by citizens working or investing in other countries'; ''; 'Minus money foreigners make in the country'; ''; 'So, GNI shows how much income the country’s people actually get, no matter where they earn it.'};
            app.Label_8 = uilabel(app.ExplanationTab);
            app.Label_8.Text = {'Life expectancy is the average number of years a person is expected to live.'; ''; 'It’s based on current health conditions, living standards, and medical care in a country.'; ''; 'For example, if the life expectancy in a country is 75, that means a baby born there today is expected to live around '; '75 years on average.'};
            app.Label_9 = uilabel(app.ExplanationTab);
            app.Label_9.Text = {'Mean years of schooling is the average number of years people aged 25 and older have spent in school.'; ''; 'It shows how long, on average, adults in a country went to school—even if they didn’t finish.'; ''; 'For example, if a country has a mean of 8 years, it means most adults went to school through about 8th grade.'; ''; ''; ''; ''; ''};
            app.GNILabel = uilabel(app.ExplanationTab);
            app.GNILabel.Text = 'GNI';
            app.LifeExpectancyLabel = uilabel(app.ExplanationTab);
            app.LifeExpectancyLabel.Text = 'Life Expectancy';
            app.MeanYearsofSchoolingLabel = uilabel(app.ExplanationTab);
            app.MeanYearsofSchoolingLabel.Text = 'Mean Years of Schooling';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ProjectUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end