classdef groupProjectApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        Question_1                     matlab.ui.container.Tab
        ChangeMapButton                matlab.ui.control.Button
        HighLabel                      matlab.ui.control.Label
        LowLabel                       matlab.ui.control.Label
        heatMapKey                     matlab.ui.control.Image
        EnterYearEditField             matlab.ui.control.NumericEditField
        EnterYearEditFieldLabel        matlab.ui.control.Label
        EnterCountryEditField          matlab.ui.control.EditField
        EnterCountryEditFieldLabel     matlab.ui.control.Label
        heatMap                        matlab.ui.control.Image
        percentChangeLabel             matlab.ui.control.Label
        PercentChangefromPreviousYearLabel  matlab.ui.control.Label
        ButtonGroup                    matlab.ui.container.ButtonGroup
        MeanYearsSchoolButton          matlab.ui.control.RadioButton
        LifeExpectancyButton_2         matlab.ui.control.RadioButton
        GNIButton_2                    matlab.ui.control.RadioButton
        Label                          matlab.ui.control.Label
        HumanDevelopmentIndexRanking2022Label  matlab.ui.control.Label
        ChoosePlotButtonGroup          matlab.ui.container.ButtonGroup
        LifeExpectancyButton           matlab.ui.control.RadioButton
        GNIButton                      matlab.ui.control.RadioButton
        schoolingAxes                  matlab.ui.control.UIAxes
        histogramAxes                  matlab.ui.control.UIAxes
        Question_2                     matlab.ui.container.Tab
        country2ChangeLabel            matlab.ui.control.Label
        country1ChangeLabel            matlab.ui.control.Label
        ErrorMessageLabel              matlab.ui.control.Label
        GraphDataButton                matlab.ui.control.Button
        Slider                         matlab.ui.control.Slider
        SliderLabel                    matlab.ui.control.Label
        MajorEventDropDown             matlab.ui.control.DropDown
        MajorEventDropDownLabel        matlab.ui.control.Label
        CountryRegion2EditField        matlab.ui.control.EditField
        CountryRegion2EditFieldLabel   matlab.ui.control.Label
        CountryRegion1EditField        matlab.ui.control.EditField
        CountryRegion1EditFieldLabel   matlab.ui.control.Label
        SelectComparisonDropDown       matlab.ui.control.DropDown
        SelectComparisonDropDownLabel  matlab.ui.control.Label
        CompareCountriesandRegionsOverTimeandThroughCrisesLabel  matlab.ui.control.Label
        UIAxes_2A                      matlab.ui.control.UIAxes
        UIAxesA                        matlab.ui.control.UIAxes
        explanationTab                 matlab.ui.container.Tab
        Label_8                        matlab.ui.control.Label
        Label_7                        matlab.ui.control.Label
        MeanYearsofSchoolingLabel      matlab.ui.control.Label
        Label_6                        matlab.ui.control.Label
        Label_5                        matlab.ui.control.Label
        LifeExpectancyLabel            matlab.ui.control.Label
        Label_4                        matlab.ui.control.Label
        Label_3                        matlab.ui.control.Label
        ItincludesLabel                matlab.ui.control.Label
        Label_2                        matlab.ui.control.Label
        GNILabel                       matlab.ui.control.Label
    end

    
    properties (Access = public)
        % Properties for Question 1
        totalLEstats = {}  % All the organized data from the startup can be used in all functions
        totalGNIstats = {} % ^^
        totalSchoolStats = {} % ^^
        countries = {} % List of countries in alpha order
        HDIrank = {} % HDI rank of the country

        % Properties for Question 2
        s = [] % data structue with all country arrays
        country1 = [] % array of country 1
        country2 = [] % array of country 2
        years = [] % years vector
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            sortData(app)
            presetFunc(app)
            mapFunc(app)
            app.ErrorMessageLabel.Visible = 'off';
        end

        % Value changed function: EnterCountryEditField
        function EnterCountryEditFieldValueChanged(app, event)
            value = app.EnterCountryEditField.Value;
            statisticsFunc(app)
            histogramFunc(app)
            schoolingGraphFunc(app)
        end

        % Value changed function: EnterYearEditField
        function EnterYearEditFieldValueChanged(app, event)
            value = app.EnterYearEditField.Value;
            statisticsFunc(app)
            histogramFunc(app)
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            statisticsFunc(app)
            histogramFunc(app)
        end

        % Selection changed function: ChoosePlotButtonGroup
        function ChoosePlotButtonGroupSelectionChanged(app, event)
            selectedButton = app.ChoosePlotButtonGroup.SelectedObject;
            schoolingGraphFunc(app)
        end

        % Button pushed function: ChangeMapButton
        function ChangeMapButtonPushed(app, event)
            mapFunc(app)
        end

        % Value changed function: SelectComparisonDropDown
        function SelectComparisonDropDownValueChanged(app, event)
            value = app.SelectComparisonDropDown.Value;
            Project(app);
            Plot(app);
            statisticsFunc2(app)
        end

        % Button pushed function: GraphDataButton
        function GraphDataButtonPushed(app, event)
            Project(app);
            Plot(app);
            statisticsFunc2(app)
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            value = app.Slider.Value;
            
            x_target = app.Slider.Value;

            if ~isempty(app.CountryRegion1EditField.Value) || ~isempty(app.CountryRegion2EditField.Value)
                %% New function interp1 for linear interpolation
                % This just interpolates values like the method learned in
                % class but within one function
                
                % Interpolate for Country 1
                y1_target = interp1(app.years, app.country1, x_target, 'linear');

                % Interpolate for Country 2
                y2_target = interp1(app.years, app.country2, x_target, 'linear');

                %% New function findall for linear interpolation
                % Delete existing slider dots (optional: tag them and
                % delete by tag)
                delete(findall(app.UIAxesA, 'Tag', 'SliderDot'))
                delete(findall(app.UIAxes_2A, 'Tag', 'SliderDot'))

                % Plot new interpolated point (slider dot)
                %% New function isnan for linear interpolation
                % Makes sure the target value 1 is a number and plots
                if ~isnan(y1_target)
                    plot(app.UIAxesA, x_target, y1_target, 'kx',...
                        'MarkerSize', 10, 'LineWidth', 2, 'Tag', 'SliderDot');
                end

                % Makes sure the target value 2 is a number and plots
                if ~isnan(y2_target)
                    plot(app.UIAxes_2A, x_target, y2_target, 'kx',...
                        'MarkerSize', 10, 'LineWidth', 2, 'Tag', 'SliderDot');
                end
            end

        end

        % Value changed function: MajorEventDropDown
        function MajorEventDropDownValueChanged(app, event)
            value = app.MajorEventDropDown.Value;
            statisticsFunc2(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Question_1 = uitab(app.TabGroup);
            app.Question_1.Title = 'Question 1';
            app.histogramAxes = uiaxes(app.Question_1);
            title(app.histogramAxes, 'Histogram of Region')
            xlabel(app.histogramAxes, 'X')
            ylabel(app.histogramAxes, 'Y')
            zlabel(app.histogramAxes, 'Z')
            app.schoolingAxes = uiaxes(app.Question_1);
            title(app.schoolingAxes, 'Mean Years Schooling Scatterplot')
            xlabel(app.schoolingAxes, 'X')
            ylabel(app.schoolingAxes, 'Y')
            zlabel(app.schoolingAxes, 'Z')
            app.ChoosePlotButtonGroup = uibuttongroup(app.Question_1);
            app.ChoosePlotButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ChoosePlotButtonGroupSelectionChanged, true);
            app.ChoosePlotButtonGroup.Title = 'Choose Plot';
            app.GNIButton = uiradiobutton(app.ChoosePlotButtonGroup);
            app.GNIButton.Text = 'GNI';
            app.GNIButton.Value = true;
            app.LifeExpectancyButton = uiradiobutton(app.ChoosePlotButtonGroup);
            app.LifeExpectancyButton.Text = 'Life Expectancy';
            app.HumanDevelopmentIndexRanking2022Label = uilabel(app.Question_1);
            app.HumanDevelopmentIndexRanking2022Label.Text = {'Human Development '; 'Index Ranking (2022)'};
            app.Label = uilabel(app.Question_1);
            app.Label.Text = '#';
            app.ButtonGroup = uibuttongroup(app.Question_1);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.GNIButton_2 = uiradiobutton(app.ButtonGroup);
            app.GNIButton_2.Text = 'GNI';
            app.GNIButton_2.Value = true;
            app.LifeExpectancyButton_2 = uiradiobutton(app.ButtonGroup);
            app.LifeExpectancyButton_2.Text = 'Life Expectancy';
            app.MeanYearsSchoolButton = uiradiobutton(app.ButtonGroup);
            app.MeanYearsSchoolButton.Text = 'Mean Years of School';
            app.PercentChangefromPreviousYearLabel = uilabel(app.Question_1);
            app.PercentChangefromPreviousYearLabel.Text = {'Percent Change'; 'from Previous Year'};
            app.percentChangeLabel = uilabel(app.Question_1);
            app.percentChangeLabel.Text = '#';
            app.heatMap = uiimage(app.Question_1);
            app.EnterCountryEditFieldLabel = uilabel(app.Question_1);
            app.EnterCountryEditFieldLabel.Text = 'Enter Country';
            app.EnterCountryEditField = uieditfield(app.Question_1, 'text');
            app.EnterCountryEditField.ValueChangedFcn = createCallbackFcn(app, @EnterCountryEditFieldValueChanged, true);
            app.EnterYearEditFieldLabel = uilabel(app.Question_1);
            app.EnterYearEditFieldLabel.Text = 'Enter Year';
            app.EnterYearEditField = uieditfield(app.Question_1, 'numeric');
            app.EnterYearEditField.ValueChangedFcn = createCallbackFcn(app, @EnterYearEditFieldValueChanged, true);
            app.heatMapKey = uiimage(app.Question_1);
            app.LowLabel = uilabel(app.Question_1);
            app.LowLabel.Text = 'Low';
            app.HighLabel = uilabel(app.Question_1);
            app.HighLabel.Text = 'High';
            app.ChangeMapButton = uibutton(app.Question_1, 'push');
            app.ChangeMapButton.ButtonPushedFcn = createCallbackFcn(app, @ChangeMapButtonPushed, true);
            app.ChangeMapButton.Text = 'Change Map';
            app.Question_2 = uitab(app.TabGroup);
            app.Question_2.Title = 'Question 2';
            app.UIAxesA = uiaxes(app.Question_2);
            title(app.UIAxesA, 'Country / Region 1')
            xlabel(app.UIAxesA, 'X')
            ylabel(app.UIAxesA, 'Y')
            zlabel(app.UIAxesA, 'Z')
            app.UIAxes_2A = uiaxes(app.Question_2);
            title(app.UIAxes_2A, 'Country / Region 2')
            xlabel(app.UIAxes_2A, 'X')
            ylabel(app.UIAxes_2A, 'Y')
            zlabel(app.UIAxes_2A, 'Z')
            app.CompareCountriesandRegionsOverTimeandThroughCrisesLabel = uilabel(app.Question_2);
            app.CompareCountriesandRegionsOverTimeandThroughCrisesLabel.Text = 'Compare Countries and Regions Over Time and Through Crises:';
            app.SelectComparisonDropDownLabel = uilabel(app.Question_2);
            app.SelectComparisonDropDownLabel.Text = 'Select Comparison';
            app.SelectComparisonDropDown = uidropdown(app.Question_2);
            app.SelectComparisonDropDown.Items = {'GNI', 'Life Expectancy', 'Mean Years of Schooling'};
            app.SelectComparisonDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectComparisonDropDownValueChanged, true);
            app.SelectComparisonDropDown.Value = 'GNI';
            app.CountryRegion1EditFieldLabel = uilabel(app.Question_2);
            app.CountryRegion1EditFieldLabel.Text = 'Country / Region 1';
            app.CountryRegion1EditField = uieditfield(app.Question_2, 'text');
            app.CountryRegion2EditFieldLabel = uilabel(app.Question_2);
            app.CountryRegion2EditFieldLabel.Text = 'Country / Region 2';
            app.CountryRegion2EditField = uieditfield(app.Question_2, 'text');
            app.MajorEventDropDownLabel = uilabel(app.Question_2);
            app.MajorEventDropDownLabel.Text = 'Major Event';
            app.MajorEventDropDown = uidropdown(app.Question_2);
            app.MajorEventDropDown.Items = {'2002 Financial Crisis', '2008 Recession', '2020 Covid-19 Pandemic'};
            app.MajorEventDropDown.ValueChangedFcn = createCallbackFcn(app, @MajorEventDropDownValueChanged, true);
            app.MajorEventDropDown.Value = '2002 Financial Crisis';
            app.SliderLabel = uilabel(app.Question_2);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.Question_2);
            app.Slider.Limits = [2000 2022];
            app.Slider.MajorTicks = [2000 2002 2004 2006 2008 2010 2012 2014 2016 2018 2020 2022];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.Value = 2000;
            app.GraphDataButton = uibutton(app.Question_2, 'push');
            app.GraphDataButton.ButtonPushedFcn = createCallbackFcn(app, @GraphDataButtonPushed, true);
            app.GraphDataButton.Text = 'Graph Data';
            app.ErrorMessageLabel = uilabel(app.Question_2);
            app.ErrorMessageLabel.Text = 'Error, one of your coutries / regions is incorrect';
            app.country1ChangeLabel = uilabel(app.Question_2);
            app.country1ChangeLabel.Text = 'Impact of Event on Country 1';
            app.country2ChangeLabel = uilabel(app.Question_2);
            app.country2ChangeLabel.Text = 'Impact of Event on Country 2';
            app.explanationTab = uitab(app.TabGroup);
            app.explanationTab.Title = 'Statistic Explanation';
            app.GNILabel = uilabel(app.explanationTab);
            app.GNILabel.Text = 'GNI:';
            app.Label_2 = uilabel(app.explanationTab);
            app.Label_2.Text = {'GNI stands for Gross National Income and is the total money earned by the people and businesses of a '; 'country in one year.'};
            app.ItincludesLabel = uilabel(app.explanationTab);
            app.ItincludesLabel.Text = 'It includes: ';
            app.Label_3 = uilabel(app.explanationTab);
            app.Label_3.Text = {'Money made inside the country (like in GDP) plus money earned by citizens working or investing in other '; 'countries minus the money foreigners make in the country'};
            app.Label_4 = uilabel(app.explanationTab);
            app.Label_4.Text = 'So GNI shows how much income the countries people actually get, no matter where they earn it.';
            app.LifeExpectancyLabel = uilabel(app.explanationTab);
            app.LifeExpectancyLabel.Text = 'Life Expectancy:';
            app.Label_5 = uilabel(app.explanationTab);
            app.Label_5.Text = 'Life expectancy is the average number of years a person is expected to live';
            app.Label_6 = uilabel(app.explanationTab);
            app.Label_6.Text = 'It is based on current health conditions, living standards, and medical care in a country';
            app.MeanYearsofSchoolingLabel = uilabel(app.explanationTab);
            app.MeanYearsofSchoolingLabel.Text = 'Mean Years of Schooling:';
            app.Label_7 = uilabel(app.explanationTab);
            app.Label_7.Text = 'Mean years of schooling is the average number of years people aged 25 and older have spent in school.';
            app.Label_8 = uilabel(app.explanationTab);
            app.Label_8.Text = 'It shows how long, on average, adults in a country went to school --- even if they did not finish.';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = groupProjectApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end